classdef CharlotteCentrodeControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CharlotteCentrodeControlUIFigure  matlab.ui.Figure
        GrabarButton                   matlab.ui.control.Button
        Direction                      matlab.ui.control.Button
        DirectivaLabel                 matlab.ui.control.Label
        AxesVoice                      matlab.ui.control.UIAxes
        ReconocimientoLabel            matlab.ui.control.Label
        NunchuckState                  matlab.ui.control.StateButton
        VoiceState                     matlab.ui.control.StateButton
        MododeoperacinLabel            matlab.ui.control.Label
        MapadenavegacinLabel           matlab.ui.control.Label
        Map                            matlab.ui.control.UIAxes
        SensoresLabel                  matlab.ui.control.Label
        MapSensors                     matlab.ui.control.UIAxes
        Lamp                           matlab.ui.control.Lamp
        SensorlateralizquierdoLampLabel_3  matlab.ui.control.Label
        SensorlateralizquierdoLampLabel_4  matlab.ui.control.Label
        SensorlateralderechoLampLabel  matlab.ui.control.Label
        SensorlateralderechoLamp       matlab.ui.control.Lamp
        SensorlateralizquierdoLampLabel  matlab.ui.control.Label
        SensorlateralizquierdoLamp     matlab.ui.control.Lamp
        SensorfrontalLampLabel         matlab.ui.control.Label
        SensorfrontalLamp              matlab.ui.control.Lamp
        DatoSensorFrontal              matlab.ui.control.Label
        DatoSensorDerecha              matlab.ui.control.Label
        DatoSensorIzquierda            matlab.ui.control.Label
        BrujulaLamp                    matlab.ui.control.Lamp
        BrujulaLabelLamp               matlab.ui.control.Label
        DatoBrujula                    matlab.ui.control.Label
        TransmisorLabel                matlab.ui.control.Label
        TransmisorLabel_2              matlab.ui.control.Label
        palancaLabel                   matlab.ui.control.Label
        NunchuckLabel                  matlab.ui.control.Label
        LabelDistancia                 matlab.ui.control.Label
        DatoDistancia                  matlab.ui.control.Label
        LabelAnchoCamino               matlab.ui.control.Label
        DatoAnchoCamino                matlab.ui.control.Label
        setup                          matlab.ui.control.Button
        ButtonInfo                     matlab.ui.control.Button
    end

    properties (Access = private)
        settingsDialog
        usersValueArray
    end
    
    methods (Access = public)
        
        function loopervoice(app)
            global Transmisor auxiliar_inicio last_point cursor_registros comando_voz
            %------while-----
            while true
                comando = comando_voz;
                if auxiliar_inicio ~= 0
                    try
                        InitialCommand = 'R'; %Iniciar Comunicacion
                        fwrite(Transmisor,InitialCommand,'uchar');
                        pause(0.1);
                        warning('off','MATLAB:serial:fscanf:unsuccessfulRead');
                        codified_packagenumber = fscanf(Transmisor);
                        codified_packagenumber = fscanf(Transmisor);
                        if isempty(codified_packagenumber) == 1
                            catcher = 'YK';                                               %Pedir paquetes almacenados
                            fwrite(Transmisor,strcat(catcher,comando),'uchar')
                        else
                            vector_packagenumber = strsplit(codified_packagenumber,'P');
                            %codified_packagenumber = '';
                            numpackages = (str2double(vector_packagenumber(2)))*1000;     %Numero de paquetes a recibir
                            %pause(0.1);
                            confirm = 'YK';                                               %Pedir paquetes almacenados
                            fwrite(Transmisor,strcat(confirm,comando),'uchar')
                            
                            for i=1:numpackages
                                cursor_registros = cursor_registros + 1;
                                SensorReadingsPackage = fscanf(Transmisor);
                                SensorReadingsChar = char(SensorReadingsPackage);
                                LengthSensorReadings = length(SensorReadingsChar);
                                SensorReadingSplit = strsplit(SensorReadingsChar,{'M','F','L','R','C'});
                                MovementReading(cursor_registros,1) = str2double(SensorReadingSplit(2))*1000; %lecturas de los movimientos ejecutados
                                FrontReading(cursor_registros,1) = str2double(SensorReadingSplit(3))*1000;    %lecturas del ultrasonido Front
                                LeftReading(cursor_registros,1) = str2double(SensorReadingSplit(4))*1000;     %lecturas del ultrasonido Left
                                RightReading(cursor_registros,1) = str2double(SensorReadingSplit(5))*1000;    %lecturas del ultrasonido Right
                                CompassReading(cursor_registros,1) = str2double(SensorReadingSplit(6))*1000;  %lecturas de la brujula digital
                                
                                if(LeftReading(cursor_registros,1)>290)
                                    LeftReading(cursor_registros,1) = LeftReading(cursor_registros-1,1);
                                end
                                
                                if(RightReading(cursor_registros,1)>290)
                                    RightReading(cursor_registros,1) = RightReading(cursor_registros-1,1);
                                end
                                
                                MovementReading;FrontReading;RightReading;LeftReading;CompassReading;
                                
                                Front = num2str(FrontReading(cursor_registros,1)); Left =  num2str(LeftReading(cursor_registros,1));
                                Right = num2str(RightReading(cursor_registros,1)); Brujula = num2str(CompassReading(cursor_registros,1));
                                
                                app.DatoSensorFrontal.Text = strcat(Front,' cm'); app.DatoSensorIzquierda.Text = strcat(Left,' cm');
                                app.DatoSensorDerecha.Text = strcat(Right,' cm'); app.DatoBrujula.Text = strcat(Brujula,'°');
                                
                                AnchoCamino = num2str(RightReading(cursor_registros,1)+LeftReading(cursor_registros,1));
                                app.DatoAnchoCamino.Text = strcat(AnchoCamino,' cm');
                                
                                if (cursor_registros == 1)
                                    last_point = mapper(app,[0,0],CompassReading(1),CompassReading(1),...
                                        MovementReading(cursor_registros),FrontReading(cursor_registros),LeftReading(cursor_registros)...
                                        ,RightReading(cursor_registros));
                                else                                    
                                    for i=1:3
                                        DatosLeft(i) = LeftReading(cursor_registros,1)
                                        DatosRight(i) = RightReading(cursor_registros,1)
                                        DatosCompass(i) = CompassReading(cursor_registros,1)
                                    end
                                    
                                    DatoLeftPlot = mean(DatosLeft)
                                    DatoRightPlot = mean(DatosRight)
                                    DatoCompassPlot = mean(DatosCompass)
                                    
                                    last_point = mapper(app,last_point,CompassReading(1),DatoCompassPlot,...
                                        MovementReading(cursor_registros),FrontReading(cursor_registros),DatoLeftPlot...
                                        ,DatoRightPlot);
                                    
                                end
                                
                            end
                        end
                    catch
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    end
                else
                    fwrite(Transmisor,comando,'uchar');
                end
                auxiliar_inicio = 1;
                try
                    value = app.VoiceState.Value;
                    if value == 0
                        pause(1);
                        break
                    end
                catch
                    break
                end
            end
            %------ while
        end
        
        function looper(app)
            global Nunchuck Transmisor auxiliar_inicio last_point cursor_registros cursor_tofill SumDistance
            %------while-----
            while true
                fwrite(Nunchuck,'D','uchar');
                direccion = fscanf(Nunchuck);
                direccion = str2num(direccion);
                try
                    switch direccion
                        case 1
                            comando = '1';
                            app.Direction.Icon = 'adelante.png';
                        case 2
                            comando = '2';
                            app.Direction.Icon = 'atras.png';
                        case 3
                            comando = '3';
                            app.Direction.Icon = 'derecha.png';
                        case 4
                            comando = '4';
                            app.Direction.Icon = 'izquierda.png';
                        case 5
                            comando = '5';
                            app.Direction.Icon = 'detener.png';
                    end
                catch
                    comando = 5;
                end
                if auxiliar_inicio ~= 0
                    try
                        InitialCommand = 'R'; %Iniciar Comunicacion
                        fwrite(Transmisor,InitialCommand,'uchar');
                        pause(0.1);
                        warning('off','MATLAB:serial:fscanf:unsuccessfulRead');
                        codified_packagenumber = fscanf(Transmisor);
                        codified_packagenumber = fscanf(Transmisor);
                        if isempty(codified_packagenumber) == 1
                            catcher = 'YK';                                               %Pedir paquetes almacenados
                            fwrite(Transmisor,strcat(catcher,comando),'uchar')
                        else
                            vector_packagenumber = strsplit(codified_packagenumber,'P');
                            %codified_packagenumber = '';
                            numpackages = (str2double(vector_packagenumber(2)))*1000;     %Numero de paquetes a recibir
                            %pause(0.1);
                            confirm = 'YK';                                               %Pedir paquetes almacenados
                            fwrite(Transmisor,strcat(confirm,comando),'uchar')
                            for i=1:numpackages
                                cursor_registros = cursor_registros + 1;
                                SensorReadingsPackage = fscanf(Transmisor);
                                SensorReadingsChar = char(SensorReadingsPackage);
                                LengthSensorReadings = length(SensorReadingsChar);
                                SensorReadingSplit = strsplit(SensorReadingsChar,{'M','F','L','K','R','K','C'});
                                MovementReading(cursor_registros,1) = str2double(SensorReadingSplit(2))*1000;    %lecturas de los movimientos ejecutados
                                FrontReading(cursor_registros,1) = str2double(SensorReadingSplit(3))*1000;       %lecturas del ultrasonido Front
                                LeftReading(cursor_registros,1) = str2double(SensorReadingSplit(4))*1000;        %lecturas del ultrasonido Left
                                LeftKalmanReading(cursor_registros,1) = str2double(SensorReadingSplit(5))*1000;  %lecturas del ultrasonido Left Kalman
                                RightReading(cursor_registros,1) = str2double(SensorReadingSplit(6))*1000;       %lecturas del ultrasonido Right
                                RightKalmanReading(cursor_registros,1) = str2double(SensorReadingSplit(7))*1000; %lecturas del ultrasonido Right Kalman
                                CompassReading(cursor_registros,1) = str2double(SensorReadingSplit(8))*1000;     %lecturas de la brujula digital
                                
                                if(LeftReading(cursor_registros,1)>250)
                                    LeftReading(cursor_registros,1) = LeftReading(cursor_registros-1,1);
                                end
                                
                                if(RightReading(cursor_registros,1)>250)
                                    RightReading(cursor_registros,1) = RightReading(cursor_registros-1,1);
                                end
                                
%                                 MovementReading;FrontReading;RightReading;LeftReading;CompassReading;
                                
                                Front = num2str(FrontReading(cursor_registros,1)); Left =  num2str(LeftReading(cursor_registros,1));
                                Right = num2str(RightReading(cursor_registros,1)); Brujula = num2str(CompassReading(cursor_registros,1));
                                
                                app.DatoSensorFrontal.Text = strcat(Front,' cm'); app.DatoSensorIzquierda.Text = strcat(Left,' cm');
                                app.DatoSensorDerecha.Text = strcat(Right,' cm'); app.DatoBrujula.Text = strcat(Brujula,'°');
                                
                                AnchoCamino = num2str(20 + RightReading(cursor_registros,1) + LeftReading(cursor_registros,1));
                                app.DatoAnchoCamino.Text = strcat(AnchoCamino,' cm');
                                
                                if (cursor_registros == 1)
                                    last_point = mapper(app,[0,0],CompassReading(1),CompassReading(1),...
                                        MovementReading(cursor_registros),FrontReading(cursor_registros),LeftReading(cursor_registros)...
                                        ,RightReading(cursor_registros));
                                else
                                    if (MovementReading(cursor_registros,1) == 3 || MovementReading(cursor_registros,1) == 4)
                                           
                                        disp(cursor_tofill);
                                        SensorLeft(cursor_tofill) = LeftReading(cursor_registros,1);
                                        SensorRight(cursor_tofill) = RightReading(cursor_registros,1);
                                        SensorCompass(cursor_tofill) = CompassReading(cursor_registros,1);
                                        disp(SensorLeft);
                                        disp(SensorRight);
                                        disp(SensorCompass);                            
                                        cursor_tofill=cursor_tofill+1;
                                        
                                        if (cursor_tofill == 4)
                                             
                                             MeanSensorLeft = mean(SensorLeft);
                                             MeanSensorRight = mean(SensorRight);
                                             MeanSensorCompass = mean(SensorCompass);
                                            
                                             last_point = mapper(app,last_point,CompassReading(1),MeanSensorCompass,...
                                                 MovementReading(cursor_registros),FrontReading(cursor_registros),MeanSensorLeft...
                                                 ,MeanSensorRight);
                                             
                                             cursor_tofill=1;
                                             SensorLeft=0;
                                             SensorRight=0;
                                             SensorCompass=0;
                                        end
                                    else
                                        
                                        last_point = mapper(app,last_point,CompassReading(1),CompassReading(cursor_registros,1),...
                                            MovementReading(cursor_registros),FrontReading(cursor_registros),LeftReading(cursor_registros,1)...
                                            ,RightReading(cursor_registros,1));
                                        
                                        if (MovementReading(cursor_registros,1) == 1)
                                            SumDistance = SumDistance + 5;
                                            disp(SumDistance)
                                            aa = num2str(SumDistance);
                                            app.DatoDistancia.Text = strcat(aa,' cm');
                                        end                                        
                                    end
                                end
                            end
                        end
                    catch
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    end
                else
                    fwrite(Transmisor,comando,'uchar');
                end
                auxiliar_inicio = 1;
                try
                    value = app.NunchuckState.Value;
                    if value == 0
                        pause(1);
                        break
                    end
                catch
                    break
                end
            end
            %------ while
        end
        
        
        function last_coords = mapper(app,init_coords,init_angle,angle,movement,front,left,right)
            init_angle = -init_angle * pi / 180;
            angle = -angle * pi / 180;
            angle_aux = -angle;
            
            angle_readj = angle - (init_angle + pi/2);
            angle_readj_right = angle_readj + pi/2;
            angle_readj_left = angle_readj - pi/2;
            
            if movement == 1
                distance = -5;
            elseif movement == 2
                distance = 5;
            else
                distance =0;
            end
            
            last_coords = [init_coords(1)+distance*cos(angle_readj),...
                init_coords(2)+distance*sin(angle_readj)];
            
            right_coords = [last_coords(1)+right*cos(angle_readj_right),...
                last_coords(2)+right*sin(angle_readj_right)];
            
            left_coords = [last_coords(1)+left*cos(angle_readj_left),...
                last_coords(2)+left*sin(angle_readj_left)];
            
            front_coords = [last_coords(1)+front*cos(angle_readj),...
                last_coords(2)+front*sin(angle_readj)];
            
            
            %             plot(app.Map,front_coords(1),front_coords(2),'k*',left_coords(1),left_coords(2),'g*',right_coords(1),right_coords(2),'r*');
            plot(app.Map,left_coords(1),left_coords(2),'y*',right_coords(1),right_coords(2),'y*');
            hold(app.Map,'on');
            plot(app.Map,[init_coords(1) last_coords(1)],[init_coords(2) last_coords(2)],'w.','Linewidth',2);
            hold(app.Map,'on');
            %%plot(app.Map,last_coords(1),last_coords(2),'k*','Linewidth',10);
            
            %--------sensors--------
            front_ax = [0+front*cos(angle_aux),...
                0+front*sin(angle_aux)];
            left_ax = [0+left*cos(angle_aux+(pi/2)),...
                0+left*sin(angle_aux+(pi/2))];
            right_ax = [0+right*cos(angle_aux-(pi/2)),...
                0+right*sin(angle_aux-(pi/2))];
            
            plot(app.MapSensors,[0 front_ax(1)],[0 front_ax(2)],'b',front_ax(1),front_ax(2),'b*'...
                ,[0 left_ax(1)],[0 left_ax(2)],'g',left_ax(1),left_ax(2),'g*'...
                ,[0 right_ax(1)],[0 right_ax(2)],'r',right_ax(1),right_ax(2),'r*');
        end
        
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CharlotteCentrodeControlUIFigure.Resize = 'off';
            app.usersValueArray = {' ',' '}; % Defaults
            app.settingsDialog = []; % Set to empty in order to not cast an error at 'CloseRequest'
            inicializador = seriallist;
            try
                try
                    fclose(instrfind);
                catch
                    gg =  serial (strjoin(inicializador(4)),'BaudRate',9600,'TimeOut',2);
                    fopen(gg);
                    fclose(instrfind);
                end
            catch
                %warndlg('Dispositivos no conectados, conectelos antes de proceder.','Advertencia');
                beep;
                cuenta_regresiva = 5;
                h = warndlg('Dispositivos no conectados,la aplicacion se cerrara en 5 segundos.','Advertencia');
                for i=1:5
                    cuenta_regresiva = cuenta_regresiva - 1;
                    pause(1);
                end
                delete(h);
                delete(app.CharlotteCentrodeControlUIFigure);
            end
            
        end

        % Callback function
        function ButtonDescargaDatosPushed(app, event)
            B = [1;2;3;4;5;6;7;8;9];
            C = [11;11;12;13;14;15;16;17];
            D = [29;35;74;55;65;4854;44;34;43;4];
            
            A = [B;C;D];
            
            fileID = fopen('ZZZZZZ.txt','w');
            fprintf(fileID,'%6s %6s %6s\n','LEFT','RIGHT','OTRA');
            fprintf(fileID,'%6.2f %6.2f %6.2f\n',A);
            fclose(fileID);
        end

        % Callback function
        function ButtonInfoPushed(app, event)
            app.settingsDialog = Informacion;
            
        end

        % Close request function: CharlotteCentrodeControlUIFigure
        function CharlotteCentrodeControlUIFigureCloseRequest(app, event)
            uiresume(app.CharlotteCentrodeControlUIFigure)
            delete(app.settingsDialog)
            delete(app.CharlotteCentrodeControlUIFigure)
        end

        % Callback function
        function GrabarButtonPushed(app, event)
            global comando_voz
            VREC = audiorecorder(16000,16,1);         % Grabando audio
            app.Lamp.Color = [1 0 0];                 % Boton RECORDING ON
            recordblocking(VREC,1.5);                 % Se detiene la grabacion del audio
            app.Lamp.Color = [0.6392 0.0784 0.1804];  % Boton RECORDING OFF
            comando = getaudiodata(VREC);
            plot(app.AxesVoice,comando);              % Ploteando señal de audio
            play(VREC);
            pause(1.5)
            
            %%----------DETERMINANDO COMANDO CON RED NEURONAL--------------
            fs=VREC.sampleRate;
            tt = 0.025;     %tiempo en ms de muestras de voz 25ms
            n = tt*fs;      %Ancho ventana
            nover = 0.25*n; %overlap
            n_coefs = 16;   %MFCCs a tomar
            comando_mfcc = melcepst(comando,fs,'Mta',n_coefs,floor(3*log(fs)),n,n-nover);
            comando_vector=comando_mfcc';
            comando_vector=comando_vector(:)';
            comando_vector=comando_vector';
            load('Net0305c.mat')
            output = net0305c(comando_vector);
            direccion_voz = max(output);
            if direccion_voz == output(1)       % ADELANTE
                comando_voz = '1';
                app.Direction.Icon = 'adelante.png';
            elseif direccion_voz == output(2)   % ATRAS
                comando_voz = '2';
                app.Direction.Icon = 'atras.png';
            elseif direccion_voz == output(3)   % DERECHA
                comando_voz = '3';
                app.Direction.Icon = 'derecha.png';
            elseif direccion_voz == output(4)   % IZQUIERDA
                comando_voz = '4';
                app.Direction.Icon = 'izquierda.png';
            elseif direccion_voz == output(5)     % DETENER
                comando_voz = '5';
                app.Direction.Icon = 'detener.png';
            end
        end

        % Callback function
        function NunchuckStateValueChanged(app, event)
            global val
            value = app.NunchuckState.Value;
            switch value
                case 0 % Switch NUNCHUK OFF
                    app.NunchuckState.Icon = 'nunchuk.png';
                    app.VoiceState.Enable = 'on';
                    app.Lamp.Enable = 'off';
                    app.GrabarButton.Enable = 'off';
                case 1 % Switch NUNCHUK ON
                    if isempty(val) == 1
                        warndlg('Dispositivos no configurados, configurelos antes de proceder.','Advertencia');
                        beep;
                        app.NunchuckState.Icon = 'nunchuk.png';
                        app.VoiceState.Enable = 'on';
                        app.Lamp.Enable = 'off';
                        app.GrabarButton.Enable = 'off';
                        app.NunchuckState = 0;
                    else
                        app.NunchuckState.Icon = 'nunchuk_on.png';
                        app.VoiceState.Enable = 'off';
                        app.Lamp.Enable = 'off';
                        app.GrabarButton.Enable = 'off';
                        app.AxesVoice.HandleVisibility = 'off';
                        looper(app);
                    end
            end
        end

        % Callback function
        function VoiceStateValueChanged(app, event)
            global val
            value = app.VoiceState.Value;
            switch value
                case 0 % Switch VOICE OFF
                    app.VoiceState.Icon = 'voiceicon.png';
                    app.NunchuckState.Enable = 'on';
                    app.Lamp.Enable = 'off';
                    app.GrabarButton.Enable = 'off';
                case 1 % Switch VOICE ON
                    if isempty(val) == 1
                        warndlg('Dispositivos no configurados, configurelos antes de proceder.','Advertencia');
                        beep;
                        app.VoiceState.Icon = 'voiceicon.png';
                        app.NunchuckState.Enable = 'on';
                        app.Lamp.Enable = 'off';
                        app.GrabarButton.Enable = 'off';
                        app.VoiceState = 0;
                    else
                        app.VoiceState.Icon = 'voiceicon_on.png';
                        app.NunchuckState.Enable = 'off';
                        app.Lamp.Enable = 'on';
                        app.GrabarButton.Enable = 'on';
                        app.AxesVoice.HandleVisibility = 'on';
                        loopervoice(app);
                    end
            end
        end

        % Callback function
        function setupPushed(app, event)
            global Nunchuck Transmisor val auxiliar_inicio comando comando_voz cursor_registros cursor_tofill SumDistance
            app.settingsDialog = DeviceAttach;
            app.settingsDialog.initfcn(app.usersValueArray,app.CharlotteCentrodeControlUIFigure)
            uiwait(app.CharlotteCentrodeControlUIFigure)
            try
                val = app.settingsDialog.userData; % Get user input from dialog box when user completes dialog
                delete(app.settingsDialog); % Delete dialogbox object and close dialog
                app.settingsDialog = [];  % Set to empty in order to not cast an error at 'CloseRequest'
                app.usersValueArray = val; % Store new values
                app.TransmisorLabel.Text = val(1);
                app.NunchuckLabel.Text = val(2);
                fclose(instrfind);
                Nunchuck =  serial (strjoin(val(2)),'BaudRate',9600,'TimeOut',0.5);
                Transmisor =  serial (strjoin(val(1)),'BaudRate',9600,'TimeOut',0.5);
                fopen(Nunchuck);fopen(Transmisor);
                auxiliar_inicio = 0;
                cursor_registros = 0;
                SumDistance = 0;
                cursor_tofill = 1;
                comando = '5';
                comando_voz = '5';
            catch
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CharlotteCentrodeControlUIFigure = uifigure;
            app.CharlotteCentrodeControlUIFigure.Name = 'Charlotte  |  Centro de Control';
            app.CharlotteCentrodeControlUIFigure.CloseRequestFcn = createCallbackFcn(app, @CharlotteCentrodeControlUIFigureCloseRequest, true);
            app.GrabarButton = uibutton(app.CharlotteCentrodeControlUIFigure, 'push');
            app.GrabarButton.ButtonPushedFcn = createCallbackFcn(app, @GrabarButtonPushed, true);
            app.GrabarButton.Enable = 'off';
            app.GrabarButton.Text = ' Grabar';
            app.Direction = uibutton(app.CharlotteCentrodeControlUIFigure, 'push');
            app.Direction.Icon = 'detener.png';
            app.Direction.Text = '';
            app.DirectivaLabel = uilabel(app.CharlotteCentrodeControlUIFigure);
            app.DirectivaLabel.Text = 'Directiva';
            app.AxesVoice = uiaxes(app.CharlotteCentrodeControlUIFigure);
            app.ReconocimientoLabel = uilabel(app.CharlotteCentrodeControlUIFigure);
            app.ReconocimientoLabel.Text = 'Reconocimiento';
            app.NunchuckState = uibutton(app.CharlotteCentrodeControlUIFigure, 'state');
            app.NunchuckState.ValueChangedFcn = createCallbackFcn(app, @NunchuckStateValueChanged, true);
            app.NunchuckState.Icon = 'nunchuk.png';
            app.NunchuckState.Text = '';
            app.VoiceState = uibutton(app.CharlotteCentrodeControlUIFigure, 'state');
            app.VoiceState.ValueChangedFcn = createCallbackFcn(app, @VoiceStateValueChanged, true);
            app.VoiceState.Icon = 'voiceicon.png';
            app.VoiceState.Text = '';
            app.MododeoperacinLabel = uilabel(app.CharlotteCentrodeControlUIFigure);
            app.MododeoperacinLabel.Text = 'Modo de operación';
            app.MapadenavegacinLabel = uilabel(app.CharlotteCentrodeControlUIFigure);
            app.MapadenavegacinLabel.Text = 'Mapa de navegación';
            app.Map = uiaxes(app.CharlotteCentrodeControlUIFigure);
            title(app.Map, 'Title')
            xlabel(app.Map, 'X')
            ylabel(app.Map, 'Y')
            app.Map.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.Map.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.SensoresLabel = uilabel(app.CharlotteCentrodeControlUIFigure);
            app.SensoresLabel.Text = 'Sensores';
            app.MapSensors = uiaxes(app.CharlotteCentrodeControlUIFigure);
            title(app.MapSensors, 'Title')
            xlabel(app.MapSensors, 'X')
            ylabel(app.MapSensors, 'Y')
            app.MapSensors.XLim = [-200 200];
            app.MapSensors.XLimMode = 'manual';
            app.MapSensors.YLim = [-200 200];
            app.MapSensors.YLimMode = 'manual';
            app.MapSensors.XTick = [-200 -100 0 100 200];
            app.MapSensors.YTick = [-200 -100 0 100 200];
            app.Lamp = uilamp(app.CharlotteCentrodeControlUIFigure);
            app.Lamp.Enable = 'off';
            app.SensorlateralizquierdoLampLabel_3 = uilabel(app.CharlotteCentrodeControlUIFigure);
            app.SensorlateralizquierdoLampLabel_3.Text = 'Brújula con respecto al';
            app.SensorlateralizquierdoLampLabel_4 = uilabel(app.CharlotteCentrodeControlUIFigure);
            app.SensorlateralizquierdoLampLabel_4.Text = ' norte magnético de la tierra';
            app.SensorlateralderechoLampLabel = uilabel(app.CharlotteCentrodeControlUIFigure);
            app.SensorlateralderechoLampLabel.Text = 'Derecha';
            app.SensorlateralderechoLamp = uilamp(app.CharlotteCentrodeControlUIFigure);
            app.SensorlateralizquierdoLampLabel = uilabel(app.CharlotteCentrodeControlUIFigure);
            app.SensorlateralizquierdoLampLabel.Text = 'Izquierda';
            app.SensorlateralizquierdoLamp = uilamp(app.CharlotteCentrodeControlUIFigure);
            app.SensorfrontalLampLabel = uilabel(app.CharlotteCentrodeControlUIFigure);
            app.SensorfrontalLampLabel.Text = 'Frontal';
            app.SensorfrontalLamp = uilamp(app.CharlotteCentrodeControlUIFigure);
            app.DatoSensorFrontal = uilabel(app.CharlotteCentrodeControlUIFigure);
            app.DatoSensorFrontal.Text = '- - - - - - ';
            app.DatoSensorDerecha = uilabel(app.CharlotteCentrodeControlUIFigure);
            app.DatoSensorDerecha.Text = '- - - - - - ';
            app.DatoSensorIzquierda = uilabel(app.CharlotteCentrodeControlUIFigure);
            app.DatoSensorIzquierda.Text = '- - - - - - ';
            app.BrujulaLamp = uilamp(app.CharlotteCentrodeControlUIFigure);
            app.BrujulaLabelLamp = uilabel(app.CharlotteCentrodeControlUIFigure);
            app.BrujulaLabelLamp.Text = 'Brújula';
            app.DatoBrujula = uilabel(app.CharlotteCentrodeControlUIFigure);
            app.DatoBrujula.Text = '- - - - - - ';
            app.TransmisorLabel = uilabel(app.CharlotteCentrodeControlUIFigure);
            app.TransmisorLabel.Text = 'Configure dispositivo';
            app.TransmisorLabel_2 = uilabel(app.CharlotteCentrodeControlUIFigure);
            app.TransmisorLabel_2.Text = 'Transmisor:';
            app.palancaLabel = uilabel(app.CharlotteCentrodeControlUIFigure);
            app.palancaLabel.Text = 'Mando:';
            app.NunchuckLabel = uilabel(app.CharlotteCentrodeControlUIFigure);
            app.NunchuckLabel.Text = 'Configure dispositivo';
            app.LabelDistancia = uilabel(app.CharlotteCentrodeControlUIFigure);
            app.LabelDistancia.Text = 'Distancia recorrida :';
            app.DatoDistancia = uilabel(app.CharlotteCentrodeControlUIFigure);
            app.DatoDistancia.Text = '- - - - - - ';
            app.LabelAnchoCamino = uilabel(app.CharlotteCentrodeControlUIFigure);
            app.LabelAnchoCamino.Text = 'Ancho actual del camino :';
            app.DatoAnchoCamino = uilabel(app.CharlotteCentrodeControlUIFigure);
            app.DatoAnchoCamino.Text = '- - - - - - ';
            app.setup = uibutton(app.CharlotteCentrodeControlUIFigure, 'push');
            app.setup.ButtonPushedFcn = createCallbackFcn(app, @setupPushed, true);
            app.setup.Icon = 'setup.png';
            app.setup.Text = '';
            app.ButtonInfo = uibutton(app.CharlotteCentrodeControlUIFigure, 'push');
            app.ButtonInfo.ButtonPushedFcn = createCallbackFcn(app, @ButtonInfoPushed, true);
            app.ButtonInfo.Icon = 'info2.png';
            app.ButtonInfo.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CharlotteCentrodeControl

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CharlotteCentrodeControlUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CharlotteCentrodeControlUIFigure)
        end
    end
end