classdef DeviceAttach < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConfiguracionDispositivosUSBUIFigure  matlab.ui.Figure
        BuscarButton                 matlab.ui.control.Button
        DispositivosDropDownLabel    matlab.ui.control.Label
        ListaXbee                    matlab.ui.control.DropDown
        ButtonTransmisor             matlab.ui.control.Button
        AsignarLabel                 matlab.ui.control.Label
        DispositivosDropDown_2Label  matlab.ui.control.Label
        ListaNunchuck                matlab.ui.control.DropDown
        ButtonNunchuck               matlab.ui.control.Button
        AceptarButton                matlab.ui.control.Button
    end

    
    properties (Access = public)
        userData % Description
    end
    
    properties (Access = private)
        parentFigureHandle % Handle to figure which is resumed at object deletion/figure close
    end
    
    methods (Access = public)
        
        function initfcn(app,defaultValues,handle2parentfigure)
            app.parentFigureHandle = handle2parentfigure;
            app.userData = defaultValues; % Cell array with string entries.
            Dispositivo_xbee  = defaultValues{1};
            Dispositivo_nunchuck = defaultValues{2};
            % Function call example from cmd line:
            % myApp = dialogApp; myApp.initfcn({'Off','Off','On'})
            %
            % Inspired by:
            % https://se.mathworks.com/matlabcentral/answers/284140-call-an-mlapp-with-input-argument-s
        end
        
    end
    

    methods (Access = private)

        % Button pushed function: AceptarButton
        function AceptarButtonPushed(app, event)
            global Dispositivo_xbee Dispositivo_nunchuck
            app.userData = {Dispositivo_xbee, Dispositivo_nunchuck};
            close(app.ConfiguracionDispositivosUSBUIFigure)
        end

        % Button pushed function: BuscarButton
        function BuscarButtonPushed(app, event)
            Dispositivos = seriallist;
            Dispositivos = Dispositivos(1:length(Dispositivos));
            app.DispositivosDropDownLabel.Enable = 'on';
            app.DispositivosDropDown_2Label.Enable = 'on';
            app.ListaXbee.Enable = 'on';
            app.ListaNunchuck.Enable = 'on';
            Auxiliar = {''};
            Auxiliar =  cellstr(Dispositivos);
            app.ListaNunchuck.Items = Auxiliar;
            app.ListaNunchuck.Value = Auxiliar(1);
            app.ListaXbee.Items = Auxiliar;
            app.ListaXbee.Value = Auxiliar(1);
        end

        % Button pushed function: ButtonNunchuck
        function ButtonNunchuckPushed(app, event)
            global Dispositivo_nunchuck
            app.ButtonNunchuck.Icon = 'nunchuk_on.png';
            Dispositivo_nunchuck = app.ListaNunchuck.Value;
        end

        % Button pushed function: ButtonTransmisor
        function ButtonTransmisorPushed(app, event)
            global Dispositivo_xbee
            app.ButtonTransmisor.Icon = 'transmisor_on.png';
            Dispositivo_xbee = app.ListaXbee.Value;
        end

        % Close request function: ConfiguracionDispositivosUSBUIFigure
        function ConfiguracionDispositivosUSBUIFigureCloseRequest(app, event)
            uiresume(app.parentFigureHandle)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConfiguracionDispositivosUSBUIFigure = uifigure;
            app.ConfiguracionDispositivosUSBUIFigure.Name = 'Configuracion | Dispositivos USB';
            app.ConfiguracionDispositivosUSBUIFigure.CloseRequestFcn = createCallbackFcn(app, @ConfiguracionDispositivosUSBUIFigureCloseRequest, true);
            app.BuscarButton = uibutton(app.ConfiguracionDispositivosUSBUIFigure, 'push');
            app.BuscarButton.ButtonPushedFcn = createCallbackFcn(app, @BuscarButtonPushed, true);
            app.BuscarButton.Icon = 'search.png';
            app.BuscarButton.Text = 'Buscar';
            app.DispositivosDropDownLabel = uilabel(app.ConfiguracionDispositivosUSBUIFigure);
            app.DispositivosDropDownLabel.Enable = 'off';
            app.DispositivosDropDownLabel.Text = 'Dispositivos';
            app.ListaXbee = uidropdown(app.ConfiguracionDispositivosUSBUIFigure);
            app.ListaXbee.Items = {};
            app.ListaXbee.Enable = 'off';
            app.ListaXbee.Value = {};
            app.ButtonTransmisor = uibutton(app.ConfiguracionDispositivosUSBUIFigure, 'push');
            app.ButtonTransmisor.ButtonPushedFcn = createCallbackFcn(app, @ButtonTransmisorPushed, true);
            app.ButtonTransmisor.Icon = 'transmisor.png';
            app.ButtonTransmisor.Text = '';
            app.AsignarLabel = uilabel(app.ConfiguracionDispositivosUSBUIFigure);
            app.AsignarLabel.Text = 'Asignar';
            app.DispositivosDropDown_2Label = uilabel(app.ConfiguracionDispositivosUSBUIFigure);
            app.DispositivosDropDown_2Label.Enable = 'off';
            app.DispositivosDropDown_2Label.Text = 'Dispositivos';
            app.ListaNunchuck = uidropdown(app.ConfiguracionDispositivosUSBUIFigure);
            app.ListaNunchuck.Items = {};
            app.ListaNunchuck.Enable = 'off';
            app.ListaNunchuck.Value = {};
            app.ButtonNunchuck = uibutton(app.ConfiguracionDispositivosUSBUIFigure, 'push');
            app.ButtonNunchuck.ButtonPushedFcn = createCallbackFcn(app, @ButtonNunchuckPushed, true);
            app.ButtonNunchuck.Icon = 'nunchuk.png';
            app.ButtonNunchuck.Text = '';
            app.AceptarButton = uibutton(app.ConfiguracionDispositivosUSBUIFigure, 'push');
            app.AceptarButton.ButtonPushedFcn = createCallbackFcn(app, @AceptarButtonPushed, true);
            app.AceptarButton.Text = 'Aceptar';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DeviceAttach

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConfiguracionDispositivosUSBUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConfiguracionDispositivosUSBUIFigure)
        end
    end
end